classdef newFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure                % New File
        TabGroup        matlab.ui.container.TabGroup    % File info, Author...
        Tab             matlab.ui.container.Tab         % File info
        ButtonGroup     matlab.ui.container.ButtonGroup % File type
        RadioButton     matlab.ui.control.RadioButton   % Script
        RadioButton2    matlab.ui.control.RadioButton   % Function
        LabelEditField6 matlab.ui.control.Label         % Name
        EditField6      matlab.ui.control.EditField    
        Label5          matlab.ui.control.Label         % Description
        EditField7      matlab.ui.control.EditField    
        Tab2            matlab.ui.container.Tab         % Author Info
        LabelEditField  matlab.ui.control.Label         % Name
        EditField       matlab.ui.control.EditField    
        Label           matlab.ui.control.Label         % Title (MSc, Ph.D.)
        EditField2      matlab.ui.control.EditField    
        Label2          matlab.ui.control.Label         % University
        EditField3      matlab.ui.control.EditField    
        Label3          matlab.ui.control.Label         % Department
        EditField4      matlab.ui.control.EditField    
        Label4          matlab.ui.control.Label         % Website
        EditField5      matlab.ui.control.EditField    
        Button3         matlab.ui.control.Button        % Save info
        Label6          matlab.ui.control.Label         % Email
        EditField8      matlab.ui.control.EditField    
        Tab3            matlab.ui.container.Tab         % About
        TextArea        matlab.ui.control.TextArea      % Copyright 2017 Fr...
        Button          matlab.ui.control.Button        % Create file
        Button2         matlab.ui.control.Button        % Cancel
        Label7          matlab.ui.control.Label         % Location
        EditField9      matlab.ui.control.EditField    
        Button4         matlab.ui.control.Button        % Choose
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('author.mat')
                load('author.mat');
                app.EditField.Value = name;
                app.EditField2.Value = title_1;
                app.EditField3.Value = university;
                app.EditField4.Value = department;
                app.EditField5.Value = website;
                app.EditField8.Value = email;
            end
        end

        % Button button pushed function
        function create_File(app)
            if app.RadioButton.Value == true
                func = false;
            else
                func = true;
            end
            
            % File information
            fileName = app.EditField6.Value;
            description = app.EditField7.Value;
            location = app.EditField9.Value;
            
            if isempty(fileName)
                fig = app.UIFigure;
                message = sprintf('The name of the file cannot be empty!');
                uialert(fig,message,'Error','Icon','error');
                return;
            end
            
            % Author information
            name = app.EditField.Value;
            title_1 = app.EditField2.Value;
            university = app.EditField3.Value;
            department = app.EditField4.Value;
            website = app.EditField5.Value;
            email = app.EditField8.Value;
            
            if ~isempty(location)
                cd(location);
            end
            
            fileID = fopen(sprintf('%s.m', fileName), 'w');
            if func
                fprintf(fileID, 'function [output1,output2] = %s(input1,input2,input3)\n', fileName);
            end
            fprintf(fileID, '%% %s - %s\n\n', upper(fileName), description);
            
            if func
                fprintf(fileID, '%% Syntax:  [output1,output2] = function_name(input1,input2,input3)\n%%\n%% Inputs:\n%%    input1 - Description\n%%    input2 - Description\n%%    input3 - Description\n%%\n%% Outputs:\n%%    output1 - Description\n%%    output2 - Description\n%%\n%% Example: \n%%    Line 1 of example\n%%    Line 2 of example\n%%    Line 3 of example\n%%\n%% Other m-files required: none\n%% Subfunctions: none\n%% MAT-files required: none\n%%\n%% See also: OTHER_FUNCTION_NAME1,  OTHER_FUNCTION_NAME2\n\n');
            end
            
            % Print author information
            fprintf(fileID, '%% Author: %s, %s\n', name, title_1);
            fprintf(fileID, '%% %s, %s\n', university, department);
            fprintf(fileID, '%% Email: %s\n', email);
            fprintf(fileID, '%% Website: %s\n', website);
            fprintf(fileID, '%% %s; Last revision: %s\n\n', datetime('today'), datetime('today'));
            
            fprintf(fileID, '%%------------- BEGIN CODE --------------\n\n%% Matlab code here.\n\n%%------------- END OF CODE --------------');
            
            fclose(fileID);
            
            delete(app.UIFigure);
            
            fname = sprintf('%s.m', fileName);
            
            edit(fname);
        end

        % Button2 button pushed function
        function cancel(app)
            delete(app.UIFigure);
        end

        % Button3 button pushed function
        function save_Info(app)
            name = app.EditField.Value;
            title_1 = app.EditField2.Value;
            university = app.EditField3.Value;
            department = app.EditField4.Value;
            website = app.EditField5.Value;
            email = app.EditField8.Value;
            save('author.mat', 'name', 'title_1', 'university', 'department', 'website', 'email');
            
            fig = app.UIFigure;
            message = sprintf('Successfuly saved the author information!');
            uialert(fig,message,'Success','Icon','success');
        end

        % Button4 button pushed function
        function chooseFolder(app)
            folder_name = uigetdir(matlabroot,'Choose folder');
            app.EditField9.Value = char(folder_name);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'New File';
            setAutoResize(app, app.UIFigure, false)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'File info';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = 'File type';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Script';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Function';
            app.LabelEditField6 = uilabel(app.Tab);
            app.LabelEditField6.Text = 'Name';
            app.EditField6 = uieditfield(app.Tab, 'text');
            app.Label5 = uilabel(app.Tab);
            app.Label5.Text = 'Description';
            app.EditField7 = uieditfield(app.Tab, 'text');
            app.Label7 = uilabel(app.Tab);
            app.Label7.Text = 'Location';
            app.EditField9 = uieditfield(app.Tab, 'text');
            app.EditField9.Editable = 'off';
            app.Button4 = uibutton(app.Tab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @chooseFolder);
            app.Button4.Text = 'Choose';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Author Info';
            app.LabelEditField = uilabel(app.Tab2);
            app.LabelEditField.Text = 'Name';
            app.EditField = uieditfield(app.Tab2, 'text');
            app.Label = uilabel(app.Tab2);
            app.Label.Text = 'Title (MSc, Ph.D.)';
            app.EditField2 = uieditfield(app.Tab2, 'text');
            app.Label2 = uilabel(app.Tab2);
            app.Label2.Text = 'University';
            app.EditField3 = uieditfield(app.Tab2, 'text');
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'Department';
            app.EditField4 = uieditfield(app.Tab2, 'text');
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'Website';
            app.EditField5 = uieditfield(app.Tab2, 'text');
            app.Button3 = uibutton(app.Tab2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @save_Info);
            app.Button3.Text = 'Save info';
            app.Label6 = uilabel(app.Tab2);
            app.Label6.Text = 'Email';
            app.EditField8 = uieditfield(app.Tab2, 'text');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'About';
            app.TextArea = uitextarea(app.Tab3);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Copyright 2017 Francisco Girbal Eiras'; ''; 'Permission is hereby granted, free of charge, to any person obtaining a copy of this software and associated documentation files (the "Software"), to deal in the Software without restriction, including without limitation the rightsto use, copy, modify, merge, publish, distribute, sublicense, and/or sell copies of the Software, and to permit persons to whom the Software is furnished to do so, subject to the following conditions:'; ''; 'The above copyright notice and this permission notice shall be included in all copies or substantial portions of the Software.'; ''; 'THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM, OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE SOFTWARE.'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @create_File);
            app.Button.Text = 'Create file';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @cancel);
            app.Button2.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = newFile()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end